classdef project2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        NodeLabel                       matlab.ui.control.Label
        XCoordinateEditFieldLabel       matlab.ui.control.Label
        XCoordinateEditField            matlab.ui.control.NumericEditField
        YCoordinateEditFieldLabel       matlab.ui.control.Label
        YCoordinateEditField            matlab.ui.control.NumericEditField
        ElementLabel                    matlab.ui.control.Label
        GenerateNodeButton              matlab.ui.control.Button
        GenerateElementButton           matlab.ui.control.Button
        Node1EditFieldLabel             matlab.ui.control.Label
        Node1EditField                  matlab.ui.control.NumericEditField
        Node2EditFieldLabel             matlab.ui.control.Label
        Node2EditField                  matlab.ui.control.NumericEditField
        ReadNLELButton                  matlab.ui.control.Button
        RemoveAllButton                 matlab.ui.control.Button
        NumberofNodesEditFieldLabel     matlab.ui.control.Label
        NumberofNodesEditField          matlab.ui.control.NumericEditField
        NumberofElementsEditFieldLabel  matlab.ui.control.Label
        NumberofElementsEditField       matlab.ui.control.NumericEditField
        NodeLabelSwitchLabel            matlab.ui.control.Label
        NodeLabelSwitch                 matlab.ui.control.Switch
        ElementLabelSwitchLabel         matlab.ui.control.Label
        ElementLabelSwitch              matlab.ui.control.Switch
        SizeSlider                      matlab.ui.control.Slider
        ShowNLELTableDropDownLabel      matlab.ui.control.Label
        ShowNLELTableDropDown           matlab.ui.control.DropDown
        NodeNumberEditFieldLabel        matlab.ui.control.Label
        NodeNumberEditField             matlab.ui.control.NumericEditField
        DeleteNodeButton                matlab.ui.control.Button
        ElementNumberEditFieldLabel     matlab.ui.control.Label
        ElementNumberEditField          matlab.ui.control.NumericEditField
        DeleteElementButton             matlab.ui.control.Button
        RUNButton                       matlab.ui.control.Button
        ShowRefSwitchLabel              matlab.ui.control.Label
        ShowRefSwitch                   matlab.ui.control.Switch
        Switch                          matlab.ui.control.Switch
        ShowRefSwitch_2Label            matlab.ui.control.Label
        ShowRefSwitch_2                 matlab.ui.control.Switch
        DropDown                        matlab.ui.control.DropDown
        TablesDropDownLabel             matlab.ui.control.Label
        TablesDropDown                  matlab.ui.control.DropDown
        MagSliderLabel                  matlab.ui.control.Label
        MagSlider                       matlab.ui.control.Slider
        RESETButton                     matlab.ui.control.Button
        ColorBarButtonGroup             matlab.ui.container.ButtonGroup
        GrayButton                      matlab.ui.control.ToggleButton
        HotButton                       matlab.ui.control.ToggleButton
        SummerButton                    matlab.ui.control.ToggleButton
        JetButton                       matlab.ui.control.ToggleButton
        CoolButton                      matlab.ui.control.ToggleButton
        ParulaButton                    matlab.ui.control.ToggleButton
        OptimizationObjectiveFunctionWxuEditFieldLabel  matlab.ui.control.Label
        OptimizationObjectiveFunctionWxuEditField  matlab.ui.control.NumericEditField
        ElementOptimizationButton       matlab.ui.control.Button
        OptimizeNodeLabel               matlab.ui.control.Label
        OptimizationNode1               matlab.ui.control.NumericEditField
        WrtLabel                        matlab.ui.control.Label
        OptimizationNode2               matlab.ui.control.NumericEditField
        IncrementEditFieldLabel         matlab.ui.control.Label
        IncrementEditField              matlab.ui.control.NumericEditField
        MergeNodeOptimizationButton     matlab.ui.control.Button
        InitializeBCSwitchLabel         matlab.ui.control.Label
        InitializeBCSwitch              matlab.ui.control.Switch
        Lamp                            matlab.ui.control.Lamp
        Label                           matlab.ui.control.Label
        SizeLabel                       matlab.ui.control.Label
        SaveFiguresButton               matlab.ui.control.Button
        DESIGNINGOPTIMALTRUSSSTRUCTUREDBRIDGELabel  matlab.ui.control.Label
        OPTIMIZEALLButton               matlab.ui.control.Button
        WkgEditFieldLabel               matlab.ui.control.Label
        WkgEditField                    matlab.ui.control.NumericEditField
        UmEditFieldLabel                matlab.ui.control.Label
        UmEditField                     matlab.ui.control.NumericEditField
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        NL 
        EL
        ENL
        NoN = 0
        NoE = 0
        ElementSwitch_value = "On"
        NodeSwitch_value = "On"
        NodeSize = 20
        ElementSize = 20
        Youngsmodulus = 210 * 10^9
        Prescribed_force = -100000
        area = 10^-4
        DoFs = 0
        DoCs = 0
        PD = 2
        NPE = 2
        Fp 
        Up 
        Fu 
        Uu 
        K 
        F 
        Kuu 
        Kup 
        Kpp 
        Kpu 
        Node_flag = "On"
        Element_flag = "On"
        colorMap = jet;
        DispDirection = "U_y"
        value_mag = "On"
        value_mag2 = "On" 
        ref_stress = "On"
        ref_disp = "On"
        mag = 1
        density = 8000 %kg/m^3
        weight_sum = 0 %initial weight
        objective_func_result = 0 %initial weight x deflection
        objective_func_result_stored
        OptimizationIncrement = 0.02
        OptimNode1 = 0
        OptimNode2 = 0
        BoundaryValue = "Off"
        BC_initialization = 0
        arrow_check = false
    end
    
    methods (Access = public)
        
        function results = updateAxes(app)
            
            cla(app.UIAxes)

            hold(app.UIAxes,'on');
            
            x=[-4 -0.1 4  0.1];%x coordinates of vertices
            rectangle(app.UIAxes,"Position",x, "FaceColor", [0.5 0.5 0.5], "LineWidth", 3);
            x=[10 -0.1 4  0.1];%x coordinates of vertices
            rectangle(app.UIAxes,"Position",x, "FaceColor", [0.5 0.5 0.5], "LineWidth", 3);
            
            if app.BC_initialization == 0
    
                if app.BoundaryValue == "On"
                    nl = [0, 0];
                    app.NL = [app.NL; nl];
                    nl = [10, 0];
                    app.NL = [app.NL; nl];                        
                    app.NoN = size(app.NL,1);
                    app.NumberofNodesEditField.Value = app.NoN;            
                    x_node = app.NL(1,1);
                    y_node = app.NL(1,2);
                    x=[x_node-4 y_node-0.1 4  0.1];%x coordinates of vertices
                    rectangle(app.UIAxes,"Position",x, "FaceColor", [0.5 0.5 0.5], "LineWidth", 3)
                    x_node = app.NL(2,1);
                    y_node = app.NL(2,2);
                    x=[x_node y_node-0.1 4  0.1];%x coordinates of vertices
                    rectangle(app.UIAxes,"Position",x, "FaceColor", [0.5 0.5 0.5], "LineWidth", 3)
    
                elseif app.BoundaryValue == "Off"
                    app.NL = [];
                    x=[-4 -0.1 4  0.1];%x coordinates of vertices
                    rectangle(app.UIAxes,"Position",x, "FaceColor", [0.5 0.5 0.5], "LineWidth", 3)
                    x=[10 -0.1 4  0.1];%x coordinates of vertices
                    rectangle(app.UIAxes,"Position",x, "FaceColor", [0.5 0.5 0.5], "LineWidth", 3)
                end
                
            end
                   
            app.NoN = size(app.NL,1);
            
            app.NoE = size(app.EL,1);
             
            for i = 1 : app.NoE
                    
                plot(app.UIAxes,[app.NL(app.EL(i,1),1),app.NL(app.EL(i,2),1)], [app.NL(app.EL(i,1),2),app.NL(app.EL(i,2),2)], 'LineWidth', 3,'Color','k');
                        
            end
            
            for i = 1 : app.NoN
                
                plot(app.UIAxes,app.NL(i,1),app.NL(i,2),'o','MarkerSize',app.NodeSize,'MarkerEdgeColor','k','MarkerFaceColor','r')
                
            end
           
            switch app.NodeSwitch_value
                
                case "On"
                        
                    for i = 1:app.NoN
                        plot(app.UIAxes,app.NL(i,1),app.NL(i,2),'o','MarkerSize',app.NodeSize,'MarkerEdgeColor','k','MarkerFaceColor','r')
                        text(app.UIAxes,app.NL(i,1),app.NL(i,2),num2str(i),'Color','w','FontSize',12,'HorizontalAlignment',"center")
                    end
                
            end
            
            switch app.ElementSwitch_value
                
                case "On"
                    
                    for j = 1:app.NoE
                        plot(app.UIAxes,((app.NL(app.EL(j,1),1))+(app.NL(app.EL(j,2),1)))/2,((app.NL(app.EL(j,1),2))+(app.NL(app.EL(j,2),2)))/2,'s','MarkerSize',app.NodeSize,'MarkerEdgeColor','w','MarkerFaceColor','g')
                        text(app.UIAxes,((app.NL(app.EL(j,1),1))+(app.NL(app.EL(j,2),1)))/2,((app.NL(app.EL(j,1),2))+(app.NL(app.EL(j,2),2)))/2,num2str(j),'Color','w','FontSize',15,'HorizontalAlignment',"center")
                    end
            end
            
            if app.arrow_check == true
                x_node = [5];
                y_node = [0];
                w= 0.5; %width of triangle
                ar=0.5; % Aspect ratio for equilateral triangle
                h=ar*w;%height of triangle
                x=[x_node x_node-h x_node+h];%x coordinates of vertices
                y=[y_node-0.5 y_node+w/2-0.5 y_node+w/2-0.5];%y coordinates of vertices
                x_line = [y_node+h-0.5 y_node+h-0.5 y_node+0.6-0.5 y_node+0.6-0.5];
                y_line = [x_node+0.05 x_node-0.05 x_node-0.05 x_node+0.05];
                fill(app.UIAxes,x,y,"b")
                fill(app.UIAxes,y_line,x_line,"b")
                text(app.UIAxes,y_line(1),x_line(1)+0.2-0.5,string(100000),"FontSize",20,"color","b")
            end
                
            app.Lamp.Color = "Green";
            app.Label.Text = "Done !";
            
            
        end
        
        function results = updateStressFigure(app)
            
            app.NoN = size(app.NL,1);
            app.NoE = size(app.EL,1);
            
            switch app.ref_stress
                
                case "On"
                     
                    %draw the undeformed configuration%
            
                    %axis equal
                    cla(app.UIAxes2)
        
                    hold(app.UIAxes2,"on");
                    
                    %plot elements
                    for i = 1 : app.NoE
                        H = plot(app.UIAxes2,[app.NL(app.EL(i,1),1),app.NL(app.EL(i,2),1)], [app.NL(app.EL(i,1),2),app.NL(app.EL(i,2),2)], 'LineWidth', 3,'Color','k');
                        H.Color(4) = 0.5;
                        
                    end
                    
                    %PLOT NODES
                    for i = 1 : app.NoN
                        plot(app.UIAxes2,app.NL(i,1),app.NL(i,2),'o','MarkerSize',20,'MarkerEdgeColor','k','MarkerFaceColor','r')
                    end
                    
                    %draw undeformed config
                    
                    colormap(app.UIAxes2 , app.colorMap);
                    
                    %plot elements with stress dist
            
                    for i = 1 : app.NoE
                        
                        x = [app.ENL(app.EL(i,1),1)+app.mag*app.ENL(app.EL(i,1),9) app.ENL(app.EL(i,2),1)+app.mag*app.ENL(app.EL(i,2),9)];
                        y = [app.ENL(app.EL(i,1),2)+app.mag*app.ENL(app.EL(i,1),10) app.ENL(app.EL(i,2),2)+app.mag*app.ENL(app.EL(i,2),10)];
                        z = zeros(size(x));
                        sigma = app.Youngsmodulus * ( sqrt((app.ENL(app.EL(i,1),9)-app.ENL(app.EL(i,2),9))^2+(app.ENL(app.EL(i,1),10)-app.ENL(app.EL(i,2),10))^2)/ (sqrt((app.ENL(app.EL(i,1),1)-app.ENL(app.EL(i,2),1))^2 + (app.ENL(app.EL(i,1),2)-app.ENL(app.EL(i,2),2))^2)));
                        col = [sigma sigma];
                        surface(app.UIAxes2,[x;x],[y;y],[z;z],[col;col],"facecol","no","edgecol","interp","linew",4);
                 
                    end
                    
                case "Off"
                    
                    cla(app.UIAxes2)
        
                    hold(app.UIAxes2,"on");
                    
                    for i = 1 : app.NoE
                        
                        x = [app.ENL(app.EL(i,1),1)+app.mag*app.ENL(app.EL(i,1),9) app.ENL(app.EL(i,2),1)+app.mag*app.ENL(app.EL(i,2),9)];
                        y = [app.ENL(app.EL(i,1),2)+app.mag*app.ENL(app.EL(i,1),10) app.ENL(app.EL(i,2),2)+app.mag*app.ENL(app.EL(i,2),10)];
                        z = zeros(size(x));
                        sigma = app.Youngsmodulus * ( sqrt((app.ENL(app.EL(i,1),9)-app.ENL(app.EL(i,2),9))^2+(app.ENL(app.EL(i,1),10)-app.ENL(app.EL(i,2),10))^2)/ (sqrt((app.ENL(app.EL(i,1),1)-app.ENL(app.EL(i,2),1))^2 + (app.ENL(app.EL(i,1),2)-app.ENL(app.EL(i,2),2))^2)));
                        col = [sigma sigma];
                        surface(app.UIAxes2,[x;x],[y;y],[z;z],[col;col],"facecol","no","edgecol","interp","linew",4);
                    end


            end
            
            colorbar(app.UIAxes2)
            
            
            %plot element numbers
            
            if isequal(app.Element_flag,"On")
                for i = 1 : app.NoE
                    text(app.UIAxes2,(app.ENL(app.EL(i,1),1)+app.mag*app.ENL(app.EL(i,1),9)+app.ENL(app.EL(i,2),1)+app.mag*app.ENL(app.EL(i,2),9))/2,(app.ENL(app.EL(i,1),2)+app.mag*app.ENL(app.EL(i,1),10)+app.ENL(app.EL(i,2),2)+app.mag*app.ENL(app.EL(i,2),10))/2,num2str(i),"Color","k","FontSize",16,"HorizontalAlignment","center")
                end
            end
            
            
            %Plot nodes
            
            for i = 1 : app.NoN
                plot(app.UIAxes2,app.ENL(i,1)+app.mag*app.ENL(i,9),app.ENL(i,2)+app.mag*app.ENL(i,10),"o","MarkerSize",16,"MarkerEdgeColor","k","MarkerFaceColor",[0,0.7,0.9])
            end
            
            %plot node numbers
            
            if isequal(app.Node_flag, "On")
                
                for i= 1:app.NoN
                    text(app.UIAxes2,app.ENL(i,1)+app.mag*app.ENL(i,9),app.ENL(i,2)+app.mag*app.ENL(i,10),num2str(i),"Color","w","FontSize",12,"HorizontalAlignment","center")
                end
            end
                                  
    
        end
        
        function results = updateDisplacementFigure(app)
            
             app.NoN = size(app.NL,1);
            app.NoE = size(app.EL,1);
            
            cla(app.UIAxes3)
        
            hold(app.UIAxes3,"on");
            
            %draw the undeformed configuration%
            switch app.ref_disp
                
                case "On"
                    %axis equal
                    
                    %plot elements
                    for i = 1 : app.NoE
                        H = plot(app.UIAxes3,[app.NL(app.EL(i,1),1),app.NL(app.EL(i,2),1)], [app.NL(app.EL(i,1),2),app.NL(app.EL(i,2),2)], 'LineWidth', 3,'Color','k');
                        H.Color(4) = 0.5;
                        
                    end
                    
                    %PLOT NODES
                    for i = 1 : app.NoN
                        plot(app.UIAxes3,app.NL(i,1),app.NL(i,2),'o','MarkerSize',20,'MarkerEdgeColor','k','MarkerFaceColor','r')
                    end
              
                    colormap(app.UIAxes3 , app.colorMap)
                    
            end
            
            switch app.DispDirection
                
                case "U_x"
            
                    for i = 1 : app.NoE
                
                        x = [app.ENL(app.EL(i,1),1)+app.mag*app.ENL(app.EL(i,1),9) app.ENL(app.EL(i,2),1)+app.mag*app.ENL(app.EL(i,2),9)];
                        y = [app.ENL(app.EL(i,1),2)+app.mag*app.ENL(app.EL(i,1),10) app.ENL(app.EL(i,2),2)+app.mag*app.ENL(app.EL(i,2),10)];
                        z = zeros(size(x));
                        col = abs([app.ENL(app.EL(i,1),9) app.ENL(app.EL(i,2),9)]);
                        surface(app.UIAxes3,[x;x],[y;y],[z;z],[col;col],"facecol","no","edgecol","interp","linew",4);
                        
                    end
                            
                case "U_y"
                    
                    for i = 1 : app.NoE
                        
                        x = [app.ENL(app.EL(i,1),1)+app.mag*app.ENL(app.EL(i,1),9) app.ENL(app.EL(i,2),1)+app.mag*app.ENL(app.EL(i,2),9)];
                        y = [app.ENL(app.EL(i,1),2)+app.mag*app.ENL(app.EL(i,1),10) app.ENL(app.EL(i,2),2)+app.mag*app.ENL(app.EL(i,2),10)];
                        z = zeros(size(x));
                        col = abs([app.ENL(app.EL(i,1),10) app.ENL(app.EL(i,2),10)]);
                        surface(app.UIAxes3,[x;x],[y;y],[z;z],[col;col],"facecol","no","edgecol","interp","linew",4);
                        
                    end
            end

            colorbar(app.UIAxes3)
            %plot element numbers
            
            if isequal(app.Element_flag,"On")
                for i = 1 : app.NoE
                    text(app.UIAxes3,(app.ENL(app.EL(i,1),1)+app.mag*app.ENL(app.EL(i,1),9)+app.ENL(app.EL(i,2),1)+app.mag*app.ENL(app.EL(i,2),9))/2,(app.ENL(app.EL(i,1),2)+app.mag*app.ENL(app.EL(i,1),10)+app.ENL(app.EL(i,2),2)+app.mag*app.ENL(app.EL(i,2),10))/2,num2str(i),"Color","k","FontSize",16,"HorizontalAlignment","center")
                end
            end
            
            
            %Plot nodes
            
            for i = 1 : app.NoN
                plot(app.UIAxes3,app.ENL(i,1)+app.mag*app.ENL(i,9),app.ENL(i,2)+app.mag*app.ENL(i,10),"o","MarkerSize",16,"MarkerEdgeColor","k","MarkerFaceColor",[0,0.7,0.9])
            end
            
            %plot node numbers
            
            if isequal(app.Node_flag, "On")
                
                for i= 1:app.NoN
                    text(app.UIAxes3,app.ENL(i,1)+app.mag*app.ENL(i,9),app.ENL(i,2)+app.mag*app.ENL(i,10),num2str(i),"Color","w","FontSize",12,"HorizontalAlignment","center")
                end
            end
                                    
        
        end
        
        
        function optimized = Optimization1(app,stress_values,stress_val)
            %deleting the element having the stress_val stress value
            %if the objective function
            %is smaller than the previous case
            
            %the index of the link having the defined stres
            stress_val_indice = find(stress_values == stress_val);
            
            node1N = app.EL(stress_val_indice,1);
            node2N = app.EL(stress_val_indice,2);
            
            %removing the element from EL
            app.EL(stress_val_indice,:) = [];
            
            node1N_cond = false %not connected
            node2N_cond = false %not connected
            
            for i=1:size(app.EL,1)
                if app.EL(i,1) == node1N || app.EL(i,2) == node1N
                    node1N_cond = true %connected
                end
                if app.EL(i,1) == node2N || app.EL(i,2) == node2N
                    node2N_cond = true %connected
                end
            end
            
            %if the node is not connected to the structure
            if node1N_cond == false
                stored_1 = app.NL(node1N,:);
                app.NL(node1N,:) = [];
            end
            
            %if the node is not connected to the structure
            if node2N_cond == false
                stored_2 =  app.NL(node2N,:);
                app.NL(node2N,:) = [];
            end

            app.NumberofElementsEditField.Value = size(app.EL,1);
            
            
            %%%%%%%%%%%%%%%%%%% calculating the new system %%%%%%%%%%%%%%%%%
            %%%%%%%%%%% after discarding the least stressed element%%%%%%%%
            
         
            app.DoFs = 0;
            app.DoCs = 0;
            app.Fp = [];
            app.Up = [];
            app.Fu = [];
            app.Uu = [];
            app.K = [];
            app.F = [];
            app.Kuu = [];
            app.Kup = [];
            app.Kpp = [];
            app.Kpu = [];
            app.NPE = 2;

            app.NoN = size(app.NL,1); %rows
            app.PD = size(app.NL,2); %columns
            app.ENL = zeros(app.NoN,app.PD*6);
            app.ENL(:,1:2) = app.NL;
            
            %Neumann Assumption (zeros become one) (the ones whıch are not
            %dirichlet)
           
            for j = 1:app.NoN
                if app.ENL(j,3) == 0
                    app.ENL(j,3) = 1;
                end
                if app.ENL(j,4) == 0
                    app.ENL(j,4) = 1;
                end
            end
          
            for i=1:size(app.NL,1)
                if app.ENL(i,1) == 0 && app.ENL(i,2) == 0
                    app.ENL(i,3) = -1; %dirichlet at 0,0
                    app.ENL(i,4) = -1; %dirichlet at 0,0
                elseif app.ENL(i,1) == 10 && app.ENL(i,2) == 0
                    app.ENL(i,3) = -1; %dirichlet at 10,0
                    app.ENL(i,4) = -1; %dirichlet at 10,0
                elseif app.ENL(i,1) == 5 && app.ENL(i,2) == 0
                    app.ENL(i,3) = 1; %Neumann at 5,0
                    app.ENL(i,4) = 1; %Neumann at 5,0
                    app.ENL(i,12) = app.Prescribed_force; %predefined force in the y direction for the node at (5,0)
                end
            end
            
            app.DoCs = 0;
            app.DoFs = 0;
            
            for i = 1:app.NoN
                for j = 1:app.PD
                    if(app.ENL(i,(app.PD+j)) == -1)
                        app.DoCs = app.DoCs -1;
                        app.ENL(i,2*app.PD+j) = app.DoCs;
                        
                    else
                        app.DoFs = app.DoFs + 1;
                        app.ENL(i,2*app.PD+j) = app.DoFs;
                    end    
                end       
            end

            for i = 1:app.NoN
                for j = 1:app.PD
                    if(app.ENL(i,(2*app.PD+j)) < 0)
                        app.ENL(i,app.PD*3+j) = app.DoFs + abs(app.ENL(i,(2*app.PD+j)));
                    else
                        app.ENL(i,app.PD*3+j) = app.ENL(i,(2*app.PD+j));
                    end
                end
            end
            
            app.K = zeros(app.NoN*app.PD,app.NoN*app.PD);
            
            app.NoE = size(app.EL,1)
            
            for i = 1:app.NoE
                nl = app.EL(i,1:app.NPE);
                k = element_stiffness(nl,app.ENL,app.Youngsmodulus,app.area);
                for r = 1:app.NPE
                    for p = 1:app.PD
                        for q = 1:app.NPE
                            for s = 1:app.PD
                                row = app.ENL(nl(r),p+3*app.PD);
                                column = app.ENL(nl(q),s+3*app.PD);
                                value = k((r-1)*app.PD+p , (q-1)*app.PD+s);
                                app.K(row,column) = app.K(row,column) + value;
                                
                            end
                        end
                    end
                end
            end
            
            DOF = 0;
            for i = 1:app.NoN
                for j = 1:app.PD
                    if app.ENL(i,app.PD+j) == 1 || app.ENL(i,app.PD+j) == 0
                        DOF = DOF + 1;
                        app.Fp(DOF,1) = app.ENL(i,5*app.PD+j);
                    end
                end
            end
            
            DOC = 0; 
            for i = 1:app.NoN
                for j = 1:app.PD
                    if app.ENL(i,app.PD+j) == -1
                        DOC = DOC + 1;
                        app.Up(DOC,1) = app.ENL(i,4*app.PD+j);
                    end
                end
            end
            
            summ = app.PD * app.NoN;
            
            app.DoCs = summ - app.DoFs;
            
            app.Kpu = app.K(1:app.DoFs,1:app.DoFs);
            
            app.Kpp = app.K(1:app.DoFs, app.DoFs+1:app.DoFs+app.DoCs);
            
            app.Kuu = app.K(app.DoFs+1:app.DoCs+app.DoFs, 1:app.DoFs);
            
            app.Kup = app.K(app.DoFs+1:app.DoCs+app.DoFs, app.DoFs+1:app.DoCs+app.DoFs);
            
            app.F = app.Fp - app.Kpp * app.Up;
            
            app.Uu = inv(app.Kpu)*app.F;
            app.Fu = app.Kuu*app.Uu + app.Kup*app.Up;
            
            DOFs = 0;
            DOCs = 0;
            
            for i = 1:app.NoN
                for j = 1:app.PD
                    if app.ENL(i,app.PD+j) == 1
                        DOFs = DOFs + 1;
                        app.ENL(i,4*app.PD+j) = app.Uu(DOFs);
                    end
                end
            end
            
            for i = 1:app.NoN
                for j = 1:app.PD
                    if app.ENL(i,app.PD+j) == -1
                        DOCs = DOCs + 1;
                        app.ENL(i,5*app.PD+j) = app.Fu(DOCs);
                    end
                end
            end     


            for i=1:size(app.EL,1)
                node_number_1 = app.EL(i,1);
                node_number_2 = app.EL(i,2);
                x_coor_n1 = app.NL(node_number_1,1);
                y_coor_n1 = app.NL(node_number_1,2);
                x_coor_n2 = app.NL(node_number_2,1);
                y_coor_n2 = app.NL(node_number_2,2);
                element_weight = WeightFunction(x_coor_n1,y_coor_n1,x_coor_n2,y_coor_n2,app.density,app.area);
                app.weight_sum = app.weight_sum + element_weight;
            end
             
            for i=1:size(app.ENL,1)
                if app.ENL(i,1) == 5 && app.ENL(i,2) == 0
                    %deflection for the node at (5,0)
                    deflection = app.ENL(i,10);
                end
            end
             
            %Finding the objective function
            app.objective_func_result = abs(app.weight_sum * deflection);
            app.objective_func_result_stored = [app.objective_func_result_stored, app.objective_func_result];
            app.OptimizationObjectiveFunctionWxuEditField.Value = app.objective_func_result_stored;
            app.objective_func_result = 0;
            app.weight_sum = 0;
            
            
            if app.objective_func_result_stored(end) < app.objective_func_result_stored(end-1)
                optimized = true;
                updateStressFigure(app);
                updateDisplacementFigure(app);
                objective_func_result = app.objective_func_result_stored(end)
                
            elseif app.objective_func_result_stored(end) > app.objective_func_result_stored(end-1)
                optimized = false;
                app.EL = [app.EL; node1N,node2N];
                objective_func_result = app.objective_func_result_stored(end)
               
                
                %if the node is not connected to the structure
                if node1N_cond == false
                    app.NL(node1N,:) = stored_1;
                end
                
                %if the node is not connected to the structure
                if node2N_cond == false
                    app.NL(node2N,:) = stored_2;
                end
                app.DoFs = 0;
                app.DoCs = 0;
                app.Fp = [];
                app.Up = [];
                app.Fu = [];
                app.Uu = [];
                app.K = [];
                app.F = [];
                app.Kuu = [];
                app.Kup = [];
                app.Kpp = [];
                app.Kpu = [];
                app.NPE = 2;
    
                app.NoN = size(app.NL,1); %rows
                app.PD = size(app.NL,2); %columns
                app.ENL = zeros(app.NoN,app.PD*6);
                app.ENL(:,1:2) = app.NL;
                
                %Neumann Assumption (zeros become one) (the ones whıch are not
                %dirichlet)
               
                for j = 1:app.NoN
                    if app.ENL(j,3) == 0
                        app.ENL(j,3) = 1;
                    end
                    if app.ENL(j,4) == 0
                        app.ENL(j,4) = 1;
                    end
                end
              
                for i=1:size(app.NL,1)
                    if app.ENL(i,1) == 0 && app.ENL(i,2) == 0
                        app.ENL(i,3) = -1; %dirichlet at 0,0
                        app.ENL(i,4) = -1; %dirichlet at 0,0
                    elseif app.ENL(i,1) == 10 && app.ENL(i,2) == 0
                        app.ENL(i,3) = -1; %dirichlet at 10,0
                        app.ENL(i,4) = -1; %dirichlet at 10,0
                    elseif app.ENL(i,1) == 5 && app.ENL(i,2) == 0
                        app.ENL(i,3) = 1; %Neumann at 5,0
                        app.ENL(i,4) = 1; %Neumann at 5,0
                        app.ENL(i,12) = app.Prescribed_force; %predefined force in the y direction for the node at (5,0)
                    end
                end
                
                app.DoCs = 0;
                app.DoFs = 0;
                
                for i = 1:app.NoN
                    for j = 1:app.PD
                        if(app.ENL(i,(app.PD+j)) == -1)
                            app.DoCs = app.DoCs -1;
                            app.ENL(i,2*app.PD+j) = app.DoCs;
                            
                        else
                            app.DoFs = app.DoFs + 1;
                            app.ENL(i,2*app.PD+j) = app.DoFs;
                        end    
                    end       
                end
    
                for i = 1:app.NoN
                    for j = 1:app.PD
                        if(app.ENL(i,(2*app.PD+j)) < 0)
                            app.ENL(i,app.PD*3+j) = app.DoFs + abs(app.ENL(i,(2*app.PD+j)));
                        else
                            app.ENL(i,app.PD*3+j) = app.ENL(i,(2*app.PD+j));
                        end
                    end
                end
                
                app.K = zeros(app.NoN*app.PD,app.NoN*app.PD);
                
                app.NoE = size(app.EL,1)
                
                for i = 1:app.NoE
                    nl = app.EL(i,1:app.NPE);
                    k = element_stiffness(nl,app.ENL,app.Youngsmodulus,app.area);
                    for r = 1:app.NPE
                        for p = 1:app.PD
                            for q = 1:app.NPE
                                for s = 1:app.PD
                                    row = app.ENL(nl(r),p+3*app.PD);
                                    column = app.ENL(nl(q),s+3*app.PD);
                                    value = k((r-1)*app.PD+p , (q-1)*app.PD+s);
                                    app.K(row,column) = app.K(row,column) + value;
                                    
                                end
                            end
                        end
                    end
                end
                
                DOF = 0;
                for i = 1:app.NoN
                    for j = 1:app.PD
                        if app.ENL(i,app.PD+j) == 1 || app.ENL(i,app.PD+j) == 0
                            DOF = DOF + 1;
                            app.Fp(DOF,1) = app.ENL(i,5*app.PD+j);
                        end
                    end
                end
                
                DOC = 0; 
                for i = 1:app.NoN
                    for j = 1:app.PD
                        if app.ENL(i,app.PD+j) == -1
                            DOC = DOC + 1;
                            app.Up(DOC,1) = app.ENL(i,4*app.PD+j);
                        end
                    end
                end
                
                summ = app.PD * app.NoN;
                
                app.DoCs = summ - app.DoFs;
                
                app.Kpu = app.K(1:app.DoFs,1:app.DoFs);
                
                app.Kpp = app.K(1:app.DoFs, app.DoFs+1:app.DoFs+app.DoCs);
                
                app.Kuu = app.K(app.DoFs+1:app.DoCs+app.DoFs, 1:app.DoFs);
                
                app.Kup = app.K(app.DoFs+1:app.DoCs+app.DoFs, app.DoFs+1:app.DoCs+app.DoFs);
                
                app.F = app.Fp - app.Kpp * app.Up;
                
                app.Uu = inv(app.Kpu)*app.F;
                app.Fu = app.Kuu*app.Uu + app.Kup*app.Up;
                
                DOFs = 0;
                DOCs = 0;
                
                for i = 1:app.NoN
                    for j = 1:app.PD
                        if app.ENL(i,app.PD+j) == 1
                            DOFs = DOFs + 1;
                            app.ENL(i,4*app.PD+j) = app.Uu(DOFs);
                        end
                    end
                end
                
                for i = 1:app.NoN
                    for j = 1:app.PD
                        if app.ENL(i,app.PD+j) == -1
                            DOCs = DOCs + 1;
                            app.ENL(i,5*app.PD+j) = app.Fu(DOCs);
                        end
                    end
                end     


                for i=1:size(app.EL,1)
                    node_number_1 = app.EL(i,1);
                    node_number_2 = app.EL(i,2);
                    x_coor_n1 = app.NL(node_number_1,1);
                    y_coor_n1 = app.NL(node_number_1,2);
                    x_coor_n2 = app.NL(node_number_2,1);
                    y_coor_n2 = app.NL(node_number_2,2);
                    element_weight = WeightFunction(x_coor_n1,y_coor_n1,x_coor_n2,y_coor_n2,app.density,app.area);
                    app.weight_sum = app.weight_sum + element_weight;
                end
                 
                for i=1:size(app.ENL,1)
                    if app.ENL(i,1) == 5 && app.ENL(i,2) == 0
                        %deflection for the node at (5,0)
                        deflection = app.ENL(i,10);
                    end
                end
                 
                %Finding the objective function
                app.objective_func_result = abs(app.weight_sum * deflection);
                app.objective_func_result_stored = [app.objective_func_result];
                app.OptimizationObjectiveFunctionWxuEditField.Value = app.objective_func_result_stored;
                app.objective_func_result = 0;
                app.weight_sum = 0;
                    
                updateStressFigure(app);
                updateDisplacementFigure(app);
                
            end
        
        end
        
        function [NL,EL,structure] = elementoptimization (app,structure1NLinfo,structure1ELinfo,structure2NLinfo,structure2ELinfo)
            %it compares two structures and returns the structure having
            %the best objective function result
            
            [NL1,EL1,ENL1,K1,Uu1,Fu1,Up1,Fp1] = calculator(structure1NLinfo,structure1ELinfo,app.Prescribed_force,app.Youngsmodulus,app.area);
            value1 = objective_func_calculator(NL1,EL1,ENL1,app.density,app.area);
           
            [NL2,EL2,ENL2,K2,Uu2,Fu2,Up2,Fp2] = calculator(structure2NLinfo,structure2ELinfo,app.Prescribed_force,app.Youngsmodulus,app.area);
            value2 = objective_func_calculator(NL2,EL2,ENL2,app.density,app.area);
      
            if value1 < value2 
                NL = NL1;
                EL = EL1;
                structure = 1; % meaning structure 1 is better 
            else
                NL = NL2;
                EL = EL2;
                structure = 2; % meaning structure 2 is better 
            end
           
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateNodeButton
        function GenerateNodeButtonPushed(app, event)
            
            app.BC_initialization = app.BC_initialization + 1

            nl = [app.XCoordinateEditField.Value, app.YCoordinateEditField.Value];
            
            xvalue = app.XCoordinateEditField.Value
            
            if app.XCoordinateEditField.Value > 10 
                
                uialert(app.UIFigure,"Node position exceeds the x limit (x=10)","Input Error");
                
            elseif app.XCoordinateEditField.Value < 0 
                
                uialert(app.UIFigure,"Node position exceeds the x limit (x=0)","Input Error");
  
            elseif size(app.NL)>0
                
                if ismember(nl,app.NL,"rows")
                    
                    uialert(app.UIFigure,"Node already exists","Input Error");
                    
                else
                    app.NL = [app.NL; nl];
                                
                    app.NoN = size(app.NL,1);
                    
                    updateAxes(app);
                    
                    app.NumberofNodesEditField.Value = app.NoN;
                end
                         
            else
              
                app.NL = [app.NL; nl];
                                
                app.NoN = size(app.NL,1);
                
                updateAxes(app);
                
                app.NumberofNodesEditField.Value = app.NoN;
                
            end
        end

        % Button pushed function: GenerateElementButton
        function GenerateElementButtonPushed(app, event)
            
            el = [app.Node1EditField.Value, app.Node2EditField.Value];
            
            cond = true;
            
            if app.Node1EditField.Value > size(app.NL,1) || app.Node2EditField.Value > size(app.NL,1)
                 uialert(app.UIFigure,"Node doesn't exist","Input Error");
                 cond = false;
            end
            
            if cond == true
                x1 = app.NL(app.Node1EditField.Value,1);
                y1 = app.NL(app.Node1EditField.Value,2);
                x2 = app.NL(app.Node2EditField.Value,1);
                y2 = app.NL(app.Node2EditField.Value,2);
                                        
                if app.Node1EditField.Value == app.Node2EditField.Value
                    uialert(app.UIFigure,"Node numbers cannot be the same","Input Error");
                elseif app.Node1EditField.Value == 0
                    uialert(app.UIFigure,"Node number cannot be zero","Input Error");
                elseif app.Node2EditField.Value == 0
                    uialert(app.UIFigure,"Node number cannot be zero","Input Error");
                elseif el(1)> app.NoN
                   uialert(app.UIFigure,"Node doesn't exist","Input Error");
                elseif el(2)> app.NoN
                    uialert(app.UIFigure,"Node doesn't exist","Input Error");
                elseif el(1)< 0 || el(2)< 0
                    uialert(app.UIFigure,"Node number cannot be smaller than 1","Input Error");
                elseif sqrt((x1-x2)^2 + (y1-y2)^2) > 3
                    uialert(app.UIFigure,"Element Length is bigger than 3","Input Error");
                elseif size(app.EL)>0
                    if ismember(el,app.EL,"rows")
                        uialert(app.UIFigure,"Element already exists","Input Error");
                    else
                        app.EL = [app.EL; el];
                                        
                        app.NoE = size(app.EL,1);
                        
                        updateAxes(app);
                                        
                        app.NumberofElementsEditField.Value = app.NoE;
                    end
                else
                    app.EL = [app.EL; el];
                                    
                    app.NoE = size(app.EL,1);
                    
                    updateAxes(app);
                                    
                    app.NumberofElementsEditField.Value = app.NoE;
                end
            end
        end

        % Button pushed function: DeleteNodeButton
        function DeleteNodeButtonPushed(app, event)
            val = app.NodeNumberEditField.Value;
                        
            if val <= 0 
                uialert(app.UIFigure,"Node number cannot be less than 1","Input Error");
            elseif val > app.NoN
                uialert(app.UIFigure,"Node number exceeds the limits ","Input Error");
            else 
                app.NL(val,:) = [];
            end
            
            new_EL = [];
            
            for i=1:app.NoE
               
               if val ~= app.EL(i,1) 
                   if val ~= app.EL(i,2)
                       
                       if app.EL(i,1) > val
                           app.EL(i,1) = app.EL(i,1) -1
                       end
                       
                       if app.EL(i,2) > val
                           app.EL(i,2) = app.EL(i,2) -1
                       end
                       
                       new_EL = [new_EL; app.EL(i,:)];
                   end
               end
         
            end        
            
            app.EL = new_EL;

            app.NoN = size(app.NL,1);
            app.NoE = size(app.EL,1);
            
            app.NumberofNodesEditField.Value = app.NoN;
            app.NumberofElementsEditField.Value = app.NoE;
            
            updateAxes(app);
        end

        % Value changed function: ShowNLELTableDropDown
        function ShowNLELTableDropDownValueChanged(app, event)
            value = app.ShowNLELTableDropDown.Value;
            if value == "NL"
                fig = uifigure;
                uinode = uitable(fig,'ColumnWidth','auto','Data',app.NL);
            end
            if value == "EL"
                fig = uifigure;
                uinode = uitable(fig,'ColumnWidth','auto','Data',app.EL);
            end
        end

        % Button pushed function: DeleteElementButton
        function DeleteElementButtonPushed(app, event)
            val = app.ElementNumberEditField.Value;
            
            if val <= 0 
                uialert(app.UIFigure,"Element number cannot be less than 1","Input Error");
            elseif val > app.NoE
                uialert(app.UIFigure,"Element number exceeds the limits ","Input Error");
            else
                app.EL(val,:) = [];
            end
         
            app.NoN = size(app.NL,1);
            app.NoE = size(app.EL,1);
            
            app.NumberofNodesEditField.Value = app.NoN;
            app.NumberofElementsEditField.Value = app.NoE;

            updateAxes(app);

        end

        % Value changed function: ElementLabelSwitch
        function ElementLabelSwitchValueChanged(app, event)
            app.ElementSwitch_value = app.ElementLabelSwitch.Value;
            updateAxes(app);
        end

        % Value changed function: NodeLabelSwitch
        function NodeLabelSwitchValueChanged(app, event)
            app.NodeSwitch_value = app.NodeLabelSwitch.Value;
            updateAxes(app);
        end

        % Callback function
        function GoBackNodeButtonPushed(app, event)
          %  app.OptimizationObjectiveFunctionWxuEditField.Value = app.goBackNode;
        end

        % Button pushed function: ReadNLELButton
        function ReadNLELButtonPushed(app, event)
            app.Lamp.Color = "Yellow";
            app.Label.Text = "Reading File...";
            app.BC_initialization = app.BC_initialization + 1;
            
            [inputfile,~] = uigetfile({'*.xlsx','*.slx'},'Select File to Open');
            
            if ~isequal(inputfile,0)
                app.NL = [];
                app.EL = [];
                
                nl = readmatrix(inputfile,'Range','A:B');
                el = readmatrix(inputfile,'Range','C:D');
                
                for i = 1 : size(nl,1)
                    if ~isnan(nl(i,:))
                        app.NL = [app.NL; nl(i,:)]; 
                    end
                end   
                
                for i = 1 : size(el,1)
                    if ~isnan(el(i,:))
                        app.EL = [app.EL; el(i,:)]; 
                    end
                end
                app.NoE = size(app.EL,1);
                app.NoN = size(app.NL,1);
                app.NumberofNodesEditField.Value = app.NoN;
                app.NumberofElementsEditField.Value = app.NoE;
                updateAxes(app);
                
            end

            x_node = [5];
            y_node = [0];
            w= 0.5; %width of triangle
            ar=0.5; % Aspect ratio for equilateral triangle
            h=ar*w;%height of triangle
            x=[x_node x_node-h x_node+h];%x coordinates of vertices
            y=[y_node-0.5 y_node+w/2-0.5 y_node+w/2-0.5];%y coordinates of vertices
            x_line = [y_node+h-0.5 y_node+h-0.5 y_node+0.6-0.5 y_node+0.6-0.5];
            y_line = [x_node+0.05 x_node-0.05 x_node-0.05 x_node+0.05];
            fill(app.UIAxes,x,y,"b")
            fill(app.UIAxes,y_line,x_line,"b")
            text(app.UIAxes,y_line(1),x_line(1)+0.2-0.5,string(100000),"FontSize",20,"color","b")
            
            app.Lamp.Color = "Green";
            app.Label.Text = "Done !";
        end

        % Button pushed function: RemoveAllButton
        function RemoveAllButtonPushed(app, event)
            cla(app.UIAxes)
            app.NL = [];
            app.EL = [];
            app.ENL = [];
            app.NoE = size(app.EL,1);
            app.NoN = size(app.NL,1);
            app.NumberofNodesEditField.Value = app.NoN;
            app.NumberofElementsEditField.Value = app.NoE;

        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)

            app.Lamp.Color = "Blue";
            app.Label.Text = "Calculating...";
	        pause(0.1)
            
            dirichlet_node00_check = false;
            dirichlet_node100_check = false;
            for i=1:size(app.NL,1)
                if app.NL(i,1) == 0 &&  app.NL(i,2) == 0
                    dirichlet_node00_check = true
                end 
            end
            
            for i=1:size(app.NL,1)
                if app.NL(i,1) == 10 &&  app.NL(i,2) == 0
                    dirichlet_node100_check = true
                end 
            end
            if IsConnected(app.NL,app.EL) == true
                if dirichlet_node00_check == true && dirichlet_node100_check == true
                    app.arrow_check = true;
                    %initially assuming there is no node at (5,0) ~ false
                    cond = false
                    
                    for i=1:size(app.NL,1)
                        %if there is a node at (5,0) condition becomes true
                        if app.NL(i,1) == 5 && app.NL(i,2) == 0
                            cond = true
                        end
                    end
                    
                    if cond == false
                        uialert(app.UIFigure," Vertical force is applied to the element instead of a node ","Input Error");
                    end
                    
                    if cond == true
                        [app.NL,app.EL,app.ENL,app.K,app.Uu,app.Fu,app.Up,app.Fp] = calculator(app.NL,app.EL,app.Prescribed_force,app.Youngsmodulus,app.area);
                        updateAxes(app);
                        updateStressFigure(app);
                        updateDisplacementFigure(app);
                        value = objective_func_calculator(app.NL,app.EL,app.ENL,app.density,app.area);
                        
                        if isnan(value)
                           uialert(app.UIFigure,"Unstable Structure","Input Error")
                           app.Lamp.Color = "Red";
                           app.Label.Text = "Process Failed !";
                           app.OptimizationObjectiveFunctionWxuEditField.Value = inf;
                        elseif value > 10000
                           uialert(app.UIFigure,"Unstable Structure","Input Error")
                           app.Lamp.Color = "Red";
                           app.Label.Text = "Process Failed !";
                           app.OptimizationObjectiveFunctionWxuEditField.Value = inf;
                        else
                           app.WkgEditField.Value = TotalWeight(app.NL,app.EL,app.ENL,app.density,app.area);
                           app.UmEditField.Value = Disp(app.ENL);
                           app.OptimizationObjectiveFunctionWxuEditField.Value = value;
                           app.Lamp.Color = "Green";
                           app.Label.Text = "Done !";
                        end
                    end
    
                    
                elseif dirichlet_node00_check == true && dirichlet_node100_check == false
                    uialert(app.UIFigure,"No node at 10,0","Input Error");
                    app.Lamp.Color = "Red";
                    app.Label.Text = "Process Failed !";
                elseif dirichlet_node00_check == false && dirichlet_node100_check == true
                    uialert(app.UIFigure,"No node at 0,0","Input Error");
                    app.Lamp.Color = "Red";
                    app.Label.Text = "Process Failed !";
                elseif dirichlet_node00_check == false && dirichlet_node100_check == false
                    uialert(app.UIFigure,"No node at 0,0 and 10,0","Input Error");
                    app.Lamp.Color = "Red";
                    app.Label.Text = "Process Failed !";
                end
            else
               uialert(app.UIFigure,"Incomplete Bridge","Input Error")
               app.Lamp.Color = "Red";
               app.Label.Text = "Process Failed !"
            end
                
                
        end

        % Value changed function: ShowRefSwitch
        function ShowRefSwitchValueChanged(app, event)
            app.value_mag = app.ShowRefSwitch.Value;
            app.ref_stress = app.ShowRefSwitch.Value;
            updateStressFigure(app);
        end

        % Value changed function: ShowRefSwitch_2
        function ShowRefSwitch_2ValueChanged(app, event)
            app.value_mag2 = app.ShowRefSwitch_2.Value;
            app.ref_disp = app.ShowRefSwitch_2.Value;
            updateDisplacementFigure(app);
        end

        % Value changed function: TablesDropDown
        function TablesDropDownValueChanged(app, event)
            value = app.TablesDropDown.Value;
            
            if value == "ENL"
                fig = uifigure;
                uinode = uitable(fig,'ColumnWidth','auto','Data',app.ENL);
           
            elseif value == "Stiffness"
                fig = uifigure;
                uinode = uitable(fig,'ColumnWidth','auto','Data',app.K);
                
            elseif value == "Fp"
                fig = uifigure;
                uinode = uitable(fig,'ColumnWidth','auto','Data',app.Fp);
                
            elseif value == "Up"
                fig = uifigure;
                uinode = uitable(fig,'ColumnWidth','auto','Data',app.Up);
                
            elseif value == "Fu"
                fig = uifigure;
                uinode = uitable(fig,'ColumnWidth','auto','Data',app.Fu);
               
            elseif value == "Uu"
                fig = uifigure;
                uinode = uitable(fig,'ColumnWidth','auto','Data',app.Uu);
                
%             elseif value == "Kpu"
%                 fig = uifigure;
%                 uinode = uitable(fig,'ColumnWidth','auto','Data',app.Kpu);
%                 
%             elseif value == "Kpp"
%                 fig = uifigure;
%                 uinode = uitable(fig,'ColumnWidth','auto','Data',app.Kpp);
%                 
%             elseif value == "Kup"
%                 fig = uifigure;
%                 uinode = uitable(fig,'ColumnWidth','auto','Data',app.Kup);
%                 
%             elseif value == "Kuu"
%                 fig = uifigure;
%                 uinode = uitable(fig,'ColumnWidth','auto','Data',app.Kuu);
%             
            
            end
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.DispDirection = app.DropDown.Value;
            updateDisplacementFigure(app);
        end

        % Value changed function: SizeSlider
        function SizeSliderValueChanged(app, event)
            app.NodeSize = app.SizeSlider.Value;
            updateAxes(app);
        end

        % Value changed function: MagSlider
        function MagSliderValueChanged(app, event)
            app.mag = app.MagSlider.Value/5;
            updateStressFigure(app);
            updateDisplacementFigure(app);
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.ENL = [];
            app.NL = [];
            app.EL = [];
            app.NoE = [];
            app.NoN = [];
            app.DoFs = 0;
            app.DoCs = 0;
            app.Fp = [];
            app.Up = [];
            app.Fu = [];
            app.Uu = [];
            app.K = [];
            app.F = [];
            app.Kuu = [];
            app.Kup = [];
            app.Kpp = [];
            app.Kpu = [];
            app.NumberofNodesEditField.Value = 0;
            app.NumberofElementsEditField.Value = 0;
            cla(app.UIAxes)
            cla(app.UIAxes2)
            cla(app.UIAxes3)
            app.NodeNumberEditField.Value = 0;
            app.ElementNumberEditField.Value = 0;
            app.OptimizationObjectiveFunctionWxuEditField.Value = 0;
            app.OptimizationNode1.Value = 0;
            app.OptimizationNode2.Value = 0;   
            app.WkgEditField.Value = 0;
            app.UmEditField.Value = 0;
        end

        % Selection changed function: ColorBarButtonGroup
        function ColorBarButtonGroupSelectionChanged(app, event)
            selectedButton = app.ColorBarButtonGroup.SelectedObject;
            
            switch selectedButton
                
                             
                case  app.SummerButton
                
                    app.colorMap = summer;
                    colormap(app.UIAxes2,app.colorMap);
                    colormap(app.UIAxes3,app.colorMap);


                case  app.GrayButton
                
                    app.colorMap = gray;
                    colormap(app.UIAxes2,app.colorMap);
                    colormap(app.UIAxes3,app.colorMap);

                
                case  app.HotButton
                
                    app.colorMap = hot;
                    colormap(app.UIAxes2,app.colorMap);
                    colormap(app.UIAxes3,app.colorMap);

                
                case app.JetButton
                    
                    app.colorMap = jet;
                    colormap(app.UIAxes2,app.colorMap);
                    colormap(app.UIAxes3,app.colorMap);

            
                case app.ParulaButton
                    
                   app.colorMap = parula;
                   colormap(app.UIAxes2,app.colorMap);
                   colormap(app.UIAxes3,app.colorMap);
    
                case app.CoolButton
                    
                   app.colorMap = cool;
                   colormap(app.UIAxes2,app.colorMap);
                   colormap(app.UIAxes3,app.colorMap);
                   
            end
        end

        % Button pushed function: ElementOptimizationButton
        function ElementOptimizationButtonPushed(app, event)
            
            iteration_check = false;
            
            app.Lamp.Color = "magenta";
            app.Label.Text = "Optimizing...";
            pause(0.1)
            
            %defining stress matrix for the elements
            stress_values = [];
            %number of elements
            app.NoE = size(app.EL,1);
            
            %going through a for loop for each element & filling the stress
            %matrix
            for i = 1 : app.NoE
                sigma = app.Youngsmodulus * ( sqrt((app.ENL(app.EL(i,1),9)-app.ENL(app.EL(i,2),9))^2+(app.ENL(app.EL(i,1),10)-app.ENL(app.EL(i,2),10))^2)/ (sqrt((app.ENL(app.EL(i,1),1)-app.ENL(app.EL(i,2),1))^2 + (app.ENL(app.EL(i,1),2)-app.ENL(app.EL(i,2),2))^2)));
                stress_values = [stress_values,sigma];
            end
            
            for k = 1 : size(app.EL,1)
                %finding the index of the minimum stress value in EL
                %which element has the least stress
                
                stress_val_indice = find(stress_values == min(stress_values));
               
                %the element having the least stress is in betweeen node1N and
                %node2N
                node1N = app.EL(stress_val_indice,1);
                node2N = app.EL(stress_val_indice,2);
                
                %removing the element from new EL and it will be compared with
                %app.EL & app.NL
                new_EL = app.EL;
                new_EL(stress_val_indice,:) = [];
                new_NL = app.NL;
                %elementoptimization method will return the better structure
                %info 
                if IsConnected(new_NL,new_EL) == true
                    
                    [NL,EL,structure] = elementoptimization (app,app.NL,app.EL,new_NL,new_EL)
                    
                    if structure == 2
                        %meaning structure 2 (new structure containing deleted
                        %element is better)
                        app.NL = new_NL;
                        app.EL = new_EL;
                        [app.NL,app.EL,app.ENL,app.K,app.Uu,app.Fu,app.Up,app.Fp] = calculator(app.NL,app.EL,app.Prescribed_force,app.Youngsmodulus,app.area);
                        updateStressFigure(app);
                        updateDisplacementFigure(app);
                        value = objective_func_calculator(app.NL,app.EL,app.ENL,app.density,app.area);
                        if isnan(value)
                            uialert(app.UIFigure,"Unstable Structure","Input Error")
                            app.Lamp.Color = "Red";
                            app.Label.Text = "Process Failed !";
                            app.OptimizationObjectiveFunctionWxuEditField.Value = inf
                        elseif value > 10000
                            uialert(app.UIFigure,"Unstable Structure","Input Error")
                            app.Lamp.Color = "Red";
                            app.Label.Text = "Process Failed !";
                            app.OptimizationObjectiveFunctionWxuEditField.Value = inf
                        else
                            app.WkgEditField.Value = TotalWeight(app.NL,app.EL,app.ENL,app.density,app.area);
                            app.UmEditField.Value = Disp(app.ENL);
                            app.OptimizationObjectiveFunctionWxuEditField.Value = value;
                            iteration_check = true;
                        end
                        break
                        
                    elseif structure == 1
                        %the old design is better
                        [app.NL,app.EL,app.ENL,app.K,app.Uu,app.Fu,app.Up,app.Fp] = calculator(app.NL,app.EL,app.Prescribed_force,app.Youngsmodulus,app.area);
                        updateStressFigure(app);
                        updateDisplacementFigure(app);
                        value = objective_func_calculator(app.NL,app.EL,app.ENL,app.density,app.area);
                        app.OptimizationObjectiveFunctionWxuEditField.Value = value;
                        app.WkgEditField.Value = TotalWeight(app.NL,app.EL,app.ENL,app.density,app.area);
                        app.UmEditField.Value = Disp(app.ENL);
                        %if the old design is better, we should iterate to the
                        %other element
                       
                        %stress_values matrix does not change except the lowest should
                        %be now infinite (not to be minimum)
                        
                        stress_values(stress_val_indice) = inf;
                    end
                        
                end
            end
            
            if iteration_check == false
                uialert(app.UIFigure,"There is no element to be thrown away and give a better optimization objective function.","Input Error");
            end
            
            
            app.Lamp.Color = "Green";
            app.Label.Text = "Done !";
            
            updateAxes(app);
            
        end

        % Button pushed function: MergeNodeOptimizationButton
        function MergeNodeOptimizationButtonPushed(app, event)
            
            app.Lamp.Color = "magenta";
            app.Label.Text = "Optimizing...";
            pause(0.1)
            node1change = app.OptimNode1;
            node2 = app.OptimNode2;
            increment = app.OptimizationIncrement;
            
            if app.NL(node1change,2) == 0
                uialert(app.UIFigure," Cannot change the foundations - Bridge is not flat anymore (not completed)","Input Error");
                 app.Lamp.Color = "Red";
                 app.Label.Text = "Process Failed :) ";
            else  
                [app.NL,app.EL,value,minus_check] = nodemergeoptimization(app.NL,app.EL,node1change,node2,increment,app.Prescribed_force,app.Youngsmodulus,app.area,app.density);
                if minus_check == false
                    [app.NL,app.EL,value,minus_check] = nodemergeoptimization(app.NL,app.EL,node1change,node2,-increment,app.Prescribed_force,app.Youngsmodulus,app.area,app.density);
                end
                app.OptimizationObjectiveFunctionWxuEditField.Value = value;
                [app.NL,app.EL,app.ENL,app.K,app.Uu,app.Fu,app.Up,app.Fp] = calculator(app.NL,app.EL,app.Prescribed_force,app.Youngsmodulus,app.area);
                updateAxes(app);
                updateDisplacementFigure(app);
                updateStressFigure(app);
                app.WkgEditField.Value = TotalWeight(app.NL,app.EL,app.ENL,app.density,app.area);
                app.UmEditField.Value = Disp(app.ENL);
                app.Lamp.Color = "Green";
                app.Label.Text = "Done !";
            end
            
        end

        % Value changed function: OptimizationNode1
        function OptimizationNode1ValueChanged(app, event)
            app.OptimNode1 = app.OptimizationNode1.Value;
            
        end

        % Value changed function: OptimizationNode2
        function OptimizationNode2ValueChanged(app, event)
            app.OptimNode2 = app.OptimizationNode2.Value;
           
        end

        % Value changed function: IncrementEditField
        function IncrementEditFieldValueChanged(app, event)
            app.OptimizationIncrement = app.IncrementEditField.Value;
            
        end

        % Value changed function: InitializeBCSwitch
        function InitializeBCSwitchValueChanged(app, event)
            app.BoundaryValue = app.InitializeBCSwitch.Value;
            if app.BC_initialization > 0
                uialert(app.UIFigure,"You already entered a node. You cannot initialize the BC at this point. Initializing BC is deactived.","Input Error");
            end
            updateAxes(app);
  
        end

        % Button pushed function: SaveFiguresButton
        function SaveFiguresButtonPushed(app, event)
            exportgraphics(app.UIAxes2, "StressFigure.png");
            exportgraphics(app.UIAxes3, "DisplacementFigure.png");
        end

        % Button pushed function: OPTIMIZEALLButton
        function OPTIMIZEALLButtonPushed(app, event)
            app.Lamp.Color = "magenta";
            app.Label.Text = "Optimizing All... This might take a while...";
            pause(2)
            opcounter = 0;
            for i=1:size(app.NL)
                if app.NL(i,2) == 0
                    for j=1:size(app.NL)
                        if app.NL(j,2) ~= 0
                            opcounter = opcounter +1;
                            app.Lamp.Color = "magenta";
                            app.Label.Text = "Iteration Count = "+num2str(opcounter);
                            pause(0.001)
                            node1change = j;
                            node2 = i;
                            increment = app.OptimizationIncrement;
                            if app.NL(node1change,2) == 0
                                uialert(app.UIFigure," Cannot change the foundations - Bridge is not flat anymore (not completed)","Input Error");
                                 app.Lamp.Color = "Red";
                                 app.Label.Text = "Process Failed :) ";
                            else  
                                [app.NL,app.EL,value,minus_check] = nodemergeoptimization(app.NL,app.EL,node1change,node2,increment,app.Prescribed_force,app.Youngsmodulus,app.area,app.density);
                                if minus_check == false
                                    [app.NL,app.EL,value,minus_check] = nodemergeoptimization(app.NL,app.EL,node1change,node2,-increment,app.Prescribed_force,app.Youngsmodulus,app.area,app.density);
                                end
                                app.OptimizationObjectiveFunctionWxuEditField.Value = value;
                                app.WkgEditField.Value = TotalWeight(app.NL,app.EL,app.ENL,app.density,app.area);
                                app.UmEditField.Value = Disp(app.ENL);
                                [app.NL,app.EL,app.ENL,app.K,app.Uu,app.Fu,app.Up,app.Fp] = calculator(app.NL,app.EL,app.Prescribed_force,app.Youngsmodulus,app.area);
                                updateAxes(app);
                                updateDisplacementFigure(app);
                                updateStressFigure(app);
                                app.Lamp.Color = "Green";
                                app.Label.Text = "Done !";
                            end
                        end
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.NodeLabel = uilabel(app.GridLayout);
            app.NodeLabel.Text = 'Node';
            app.XCoordinateEditFieldLabel = uilabel(app.GridLayout);
            app.XCoordinateEditFieldLabel.Text = 'X Coordinate';
            app.XCoordinateEditField = uieditfield(app.GridLayout, 'numeric');
            app.YCoordinateEditFieldLabel = uilabel(app.GridLayout);
            app.YCoordinateEditFieldLabel.Text = 'Y Coordinate';
            app.YCoordinateEditField = uieditfield(app.GridLayout, 'numeric');
            app.ElementLabel = uilabel(app.GridLayout);
            app.ElementLabel.Text = 'Element';
            app.GenerateNodeButton = uibutton(app.GridLayout, 'push');
            app.GenerateNodeButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateNodeButtonPushed, true);
            app.GenerateNodeButton.Text = 'GenerateNode';
            app.GenerateElementButton = uibutton(app.GridLayout, 'push');
            app.GenerateElementButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateElementButtonPushed, true);
            app.GenerateElementButton.Text = 'GenerateElement';
            app.Node1EditFieldLabel = uilabel(app.GridLayout);
            app.Node1EditFieldLabel.Text = 'Node1';
            app.Node1EditField = uieditfield(app.GridLayout, 'numeric');
            app.Node2EditFieldLabel = uilabel(app.GridLayout);
            app.Node2EditFieldLabel.Text = 'Node2';
            app.Node2EditField = uieditfield(app.GridLayout, 'numeric');
            app.ReadNLELButton = uibutton(app.GridLayout, 'push');
            app.ReadNLELButton.ButtonPushedFcn = createCallbackFcn(app, @ReadNLELButtonPushed, true);
            app.ReadNLELButton.Text = 'ReadNL&EL';
            app.RemoveAllButton = uibutton(app.GridLayout, 'push');
            app.RemoveAllButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveAllButtonPushed, true);
            app.RemoveAllButton.Text = 'RemoveAll';
            app.NumberofNodesEditFieldLabel = uilabel(app.GridLayout);
            app.NumberofNodesEditFieldLabel.Text = 'NumberofNodes';
            app.NumberofNodesEditField = uieditfield(app.GridLayout, 'numeric');
            app.NumberofElementsEditFieldLabel = uilabel(app.GridLayout);
            app.NumberofElementsEditFieldLabel.Text = 'NumberofElements';
            app.NumberofElementsEditField = uieditfield(app.GridLayout, 'numeric');
            app.NodeLabelSwitchLabel = uilabel(app.GridLayout);
            app.NodeLabelSwitchLabel.Text = 'NodeLabel';
            app.NodeLabelSwitch = uiswitch(app.GridLayout, 'slider');
            app.NodeLabelSwitch.ValueChangedFcn = createCallbackFcn(app, @NodeLabelSwitchValueChanged, true);
            app.NodeLabelSwitch.Value = 'On';
            app.ElementLabelSwitchLabel = uilabel(app.GridLayout);
            app.ElementLabelSwitchLabel.Text = 'ElementLabel';
            app.ElementLabelSwitch = uiswitch(app.GridLayout, 'slider');
            app.ElementLabelSwitch.ValueChangedFcn = createCallbackFcn(app, @ElementLabelSwitchValueChanged, true);
            app.ElementLabelSwitch.Value = 'On';
            app.SizeSlider = uislider(app.GridLayout);
            app.SizeSlider.Limits = [1 100];
            app.SizeSlider.ValueChangedFcn = createCallbackFcn(app, @SizeSliderValueChanged, true);
            app.SizeSlider.Value = 13.2097144732524;
            app.ShowNLELTableDropDownLabel = uilabel(app.GridLayout);
            app.ShowNLELTableDropDownLabel.Text = 'Show NL&EL Table';
            app.ShowNLELTableDropDown = uidropdown(app.GridLayout);
            app.ShowNLELTableDropDown.Items = {'Options', 'NL', 'EL'};
            app.ShowNLELTableDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowNLELTableDropDownValueChanged, true);
            app.ShowNLELTableDropDown.Value = 'Options';
            app.NodeNumberEditFieldLabel = uilabel(app.GridLayout);
            app.NodeNumberEditFieldLabel.Text = 'Node Number';
            app.NodeNumberEditField = uieditfield(app.GridLayout, 'numeric');
            app.DeleteNodeButton = uibutton(app.GridLayout, 'push');
            app.DeleteNodeButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteNodeButtonPushed, true);
            app.DeleteNodeButton.Text = 'Delete Node';
            app.ElementNumberEditFieldLabel = uilabel(app.GridLayout);
            app.ElementNumberEditFieldLabel.Text = 'Element Number';
            app.ElementNumberEditField = uieditfield(app.GridLayout, 'numeric');
            app.DeleteElementButton = uibutton(app.GridLayout, 'push');
            app.DeleteElementButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteElementButtonPushed, true);
            app.DeleteElementButton.Text = 'Delete Element';
            app.RUNButton = uibutton(app.GridLayout, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.ShowRefSwitchLabel = uilabel(app.GridLayout);
            app.ShowRefSwitchLabel.Text = 'Show Ref';
            app.ShowRefSwitch = uiswitch(app.GridLayout, 'slider');
            app.ShowRefSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowRefSwitchValueChanged, true);
            app.ShowRefSwitch.Value = 'On';
            app.Switch = uiswitch(app.GridLayout, 'slider');
            app.ShowRefSwitch_2Label = uilabel(app.GridLayout);
            app.ShowRefSwitch_2Label.Text = 'Show Ref';
            app.ShowRefSwitch_2 = uiswitch(app.GridLayout, 'slider');
            app.ShowRefSwitch_2.ValueChangedFcn = createCallbackFcn(app, @ShowRefSwitch_2ValueChanged, true);
            app.ShowRefSwitch_2.Value = 'On';
            app.DropDown = uidropdown(app.GridLayout);
            app.DropDown.Items = {'U_x', 'U_y'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'U_y';
            app.TablesDropDownLabel = uilabel(app.GridLayout);
            app.TablesDropDownLabel.Text = 'Tables';
            app.TablesDropDown = uidropdown(app.GridLayout);
            app.TablesDropDown.Items = {'Options', 'ENL', 'Stiffness', 'Fp', 'Up', 'Fu', 'Uu'};
            app.TablesDropDown.ValueChangedFcn = createCallbackFcn(app, @TablesDropDownValueChanged, true);
            app.TablesDropDown.Value = 'Options';
            app.MagSliderLabel = uilabel(app.GridLayout);
            app.MagSliderLabel.Text = 'Mag';
            app.MagSlider = uislider(app.GridLayout);
            app.MagSlider.Limits = [1 100];
            app.MagSlider.ValueChangedFcn = createCallbackFcn(app, @MagSliderValueChanged, true);
            app.MagSlider.Value = 1;
            app.RESETButton = uibutton(app.GridLayout, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.ColorBarButtonGroup = uibuttongroup(app.GridLayout);
            app.ColorBarButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorBarButtonGroupSelectionChanged, true);
            app.ColorBarButtonGroup.Title = 'ColorBar';
            app.GrayButton = uitogglebutton(app.ColorBarButtonGroup);
            app.GrayButton.Icon = 'C:\Users\burak\Desktop\Matlab Proje 2\gray.jpg';
            app.GrayButton.Text = 'Gray';
            app.GrayButton.Value = true;
            app.HotButton = uitogglebutton(app.ColorBarButtonGroup);
            app.HotButton.Icon = 'C:\Users\burak\Desktop\Matlab Proje 2\hot.jpg';
            app.HotButton.Text = 'Hot';
            app.SummerButton = uitogglebutton(app.ColorBarButtonGroup);
            app.SummerButton.Icon = 'C:\Users\burak\Desktop\Matlab Proje 2\summer.jpg';
            app.SummerButton.Text = 'Summer';
            app.JetButton = uitogglebutton(app.ColorBarButtonGroup);
            app.JetButton.Icon = 'C:\Users\burak\Desktop\Matlab Proje 2\jet.jpg';
            app.JetButton.Text = 'Jet';
            app.CoolButton = uitogglebutton(app.ColorBarButtonGroup);
            app.CoolButton.Icon = 'C:\Users\burak\Desktop\Matlab Proje 2\cool.jpg';
            app.CoolButton.Text = 'Cool';
            app.ParulaButton = uitogglebutton(app.ColorBarButtonGroup);
            app.ParulaButton.Icon = 'C:\Users\burak\Desktop\Matlab Proje 2\parula.jpg';
            app.ParulaButton.Text = 'Parula';
            app.OptimizationObjectiveFunctionWxuEditFieldLabel = uilabel(app.GridLayout);
            app.OptimizationObjectiveFunctionWxuEditFieldLabel.Text = 'Optimization Objective Function (W x u)';
            app.OptimizationObjectiveFunctionWxuEditField = uieditfield(app.GridLayout, 'numeric');
            app.OptimizationObjectiveFunctionWxuEditField.Editable = 'off';
            app.ElementOptimizationButton = uibutton(app.GridLayout, 'push');
            app.ElementOptimizationButton.ButtonPushedFcn = createCallbackFcn(app, @ElementOptimizationButtonPushed, true);
            app.ElementOptimizationButton.Text = ' Element Optimization';
            app.OptimizeNodeLabel = uilabel(app.GridLayout);
            app.OptimizeNodeLabel.Text = 'Optimize Node';
            app.OptimizationNode1 = uieditfield(app.GridLayout, 'numeric');
            app.OptimizationNode1.ValueChangedFcn = createCallbackFcn(app, @OptimizationNode1ValueChanged, true);
            app.WrtLabel = uilabel(app.GridLayout);
            app.WrtLabel.Text = 'Wrt';
            app.OptimizationNode2 = uieditfield(app.GridLayout, 'numeric');
            app.OptimizationNode2.ValueChangedFcn = createCallbackFcn(app, @OptimizationNode2ValueChanged, true);
            app.IncrementEditFieldLabel = uilabel(app.GridLayout);
            app.IncrementEditFieldLabel.Text = 'Increment';
            app.IncrementEditField = uieditfield(app.GridLayout, 'numeric');
            app.IncrementEditField.ValueChangedFcn = createCallbackFcn(app, @IncrementEditFieldValueChanged, true);
            app.IncrementEditField.Value = 0.02;
            app.MergeNodeOptimizationButton = uibutton(app.GridLayout, 'push');
            app.MergeNodeOptimizationButton.ButtonPushedFcn = createCallbackFcn(app, @MergeNodeOptimizationButtonPushed, true);
            app.MergeNodeOptimizationButton.Text = 'MergeNodeOptimization';
            app.InitializeBCSwitchLabel = uilabel(app.GridLayout);
            app.InitializeBCSwitchLabel.Text = 'Initialize BC';
            app.InitializeBCSwitch = uiswitch(app.GridLayout, 'slider');
            app.InitializeBCSwitch.ValueChangedFcn = createCallbackFcn(app, @InitializeBCSwitchValueChanged, true);
            app.Lamp = uilamp(app.GridLayout);
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.SizeLabel = uilabel(app.GridLayout);
            app.SizeLabel.Text = 'Size';
            app.SaveFiguresButton = uibutton(app.GridLayout, 'push');
            app.SaveFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFiguresButtonPushed, true);
            app.SaveFiguresButton.Text = 'Save Figures';
            app.DESIGNINGOPTIMALTRUSSSTRUCTUREDBRIDGELabel = uilabel(app.GridLayout);
            app.DESIGNINGOPTIMALTRUSSSTRUCTUREDBRIDGELabel.Text = 'DESIGNING OPTIMAL TRUSS-STRUCTURED BRIDGE';
            app.OPTIMIZEALLButton = uibutton(app.GridLayout, 'push');
            app.OPTIMIZEALLButton.ButtonPushedFcn = createCallbackFcn(app, @OPTIMIZEALLButtonPushed, true);
            app.OPTIMIZEALLButton.Text = 'OPTIMIZE ALL';
            app.WkgEditFieldLabel = uilabel(app.GridLayout);
            app.WkgEditFieldLabel.Text = 'W (kg)';
            app.WkgEditField = uieditfield(app.GridLayout, 'numeric');
            app.WkgEditField.Editable = 'off';
            app.UmEditFieldLabel = uilabel(app.GridLayout);
            app.UmEditFieldLabel.Text = 'U (m)';
            app.UmEditField = uieditfield(app.GridLayout, 'numeric');
            app.UmEditField.Editable = 'off';
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'Displacement Figure')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Stress Figure')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Bridge Configuration')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-2 12];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end